classdef Printer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        PrintTab                 matlab.ui.container.Tab
        PrintStartButton         matlab.ui.control.Button
        PrintStopButton          matlab.ui.control.Button
        FilePanel                matlab.ui.container.Panel
        PrintBrowseButton        matlab.ui.control.Button
        PrintFilepathField       matlab.ui.control.EditField
        CalculateETACheckBox     matlab.ui.control.CheckBox
        RealtimeTab              matlab.ui.container.Tab
        RtGoButton               matlab.ui.control.Button
        RtQueueingPanel          matlab.ui.container.Panel
        RtAddButton              matlab.ui.control.Button
        RtClearButton            matlab.ui.control.Button
        ViewButton               matlab.ui.control.Button
        RtExecuteButton          matlab.ui.control.Button
        CommandTabGroup          matlab.ui.container.TabGroup
        LineTab                  matlab.ui.container.Tab
        XLabel_2                 matlab.ui.control.Label
        RtXLineField             matlab.ui.control.NumericEditField
        YEditFieldLabel_5        matlab.ui.control.Label
        RtYLineField             matlab.ui.control.NumericEditField
        ZLabel                   matlab.ui.control.Label
        RtZLineField             matlab.ui.control.NumericEditField
        RtRelativeCheckBox       matlab.ui.control.CheckBox
        CurveTab                 matlab.ui.container.Tab
        XLabel                   matlab.ui.control.Label
        RtXCurveField            matlab.ui.control.NumericEditField
        YEditFieldLabel_6        matlab.ui.control.Label
        RtYCurveField            matlab.ui.control.NumericEditField
        ALabel                   matlab.ui.control.Label
        RtACurveField            matlab.ui.control.NumericEditField
        indegreesCCWLabel        matlab.ui.control.Label
        DecelLabel               matlab.ui.control.Label
        RtDecelField             matlab.ui.control.NumericEditField
        AccelLabel               matlab.ui.control.Label
        RtAccelField             matlab.ui.control.NumericEditField
        CommandsqueuedLabel      matlab.ui.control.Label
        RtQueueField             matlab.ui.control.NumericEditField
        VelLabel                 matlab.ui.control.Label
        RtVelocityField          matlab.ui.control.NumericEditField
        DebugTab                 matlab.ui.container.Tab
        DebugSendButton          matlab.ui.control.Button
        DebugResetButton         matlab.ui.control.Button
        CommandEditFieldLabel_2  matlab.ui.control.Label
        DebugCommandField        matlab.ui.control.EditField
        ResponseTextAreaLabel_2  matlab.ui.control.Label
        DebugResponseText        matlab.ui.control.TextArea
        DebugStringCheckBox      matlab.ui.control.CheckBox
        stringLabel_2            matlab.ui.control.Label
        DebugQueryButton         matlab.ui.control.Button
        DebugHomeButton          matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
        DisconnectButton         matlab.ui.control.Button
        ConnectedLabel           matlab.ui.control.Label
        ConnectedLamp            matlab.ui.control.Lamp
        EStopButton              matlab.ui.control.Button
    end


    methods (Access = private)
        
        function GCodeStream(app, input)
            global ESP;
    	    global CURRENT_POS;
    	    global CURRENT_SPEED;
    	    CURRENT_POS = [0,0,0];
    	    CURRENT_SPEED = 50;
            oldTO = ESP.Timeout;
        
            fid = fopen(input);
            frewind(fid);
            
            lineNum = 0;
            lineEnd = numel(cell2mat(textscan(fid,'%1c%*[^\n]')));
            frewind(fid);
            
            h = waitbar(0,'Printing...','Name',input,...
                'CreateCancelBtn',...
                'setappdata(gcbf,''canceling'',1)');
            setappdata(h,'canceling',0);
            setappdata(h,'timesClicked',0);
            set(h, 'CloseRequestFcn', @WaitBarClose);
            
                function WaitBarClose(hObject, ~)
                    setappdata(hObject,'canceling', 1);
                    
                    lastClicked = getappdata(hObject,'timesClicked');
                    setappdata(hObject,'timesClicked', lastClicked + 1)
                    if lastClicked > 5
                        delete(hObject);
                    end
                end
            
            firstLine = true;
            while true
                
                if getappdata(h,'canceling')
                    delete(h);
                    %app.PrintStartButton.Enable = 'on';
                    return;
                end
                
                [GParams, linesRead] = GetGLine(fid);
                
                if isempty(GParams)
                    break; % End of file
                end
                
                lineNum = lineNum + linesRead;
                
                % Update progress bar
                waitbar(lineNum/lineEnd);
                
                [x, y, z, vel] = GParse(GParams); % Parse for coordinates
                
                % Generate command
                [asciiCommand, time] = G1(x, y, z, vel);
                queueSize = 10;
                while queueSize > 5
                    queueSize = Query(app, '1HQ?;', false, time * 1.1); % Wait for ping from last command before sending next
                    app.DebugResponseText.Value = num2str(queueSize);
                end
                Send(asciiCommand); % Send next
                pause(1);
                firstLine = false;
            end
            
            ESP.Timeout = oldTO;
            delete(h);
            uiwait(msgbox('Print completed!', 'Done'));

        end
        
        function [command, time] = RtEvaluate(app) % Realtime command evaluation. Separated for queueing.
            global ESP;
            %global CURRENT_POS;
            global CURRENT_SPEED;
            global CURRENT_ACCEL;
            global CURRENT_DECEL;
            
            if isempty(ESP)
                app.DebugResponseText.Value = '[disconnected]';
                return; 
            end
            
            %app.DebugResponseText.Value = 'Moving...';
            
            CURRENT_SPEED = app.RtVelocityField.Value;
            
            commandTabs = get(app.CommandTabGroup, 'Children');
            
            if (app.CommandTabGroup.SelectedTab == commandTabs(1))
                x = app.RtXLineField.Value;
                y = app.RtYLineField.Value;
                z = app.RtZLineField.Value;
                [command, time] = G1(x, y, z, CURRENT_SPEED, app.RtRelativeCheckBox.Value);
            else
                x = app.RtXCurveField.Value;
                y = app.RtYCurveField.Value;
                a = app.RtACurveField.Value;
                [command, time] = G3(x, y, a, CURRENT_SPEED);
            end
            
            if app.RtAccelField.Value ~= CURRENT_ACCEL
                CURRENT_ACCEL = app.RtAccelField.Value;
                command = [sprintf('1HA%0.5f;', CURRENT_ACCEL) command];
            end
            
            if app.RtDecelField.Value ~= CURRENT_DECEL
                CURRENT_DECEL = app.RtDecelField.Value;
                command = [sprintf('1HD%0.5f;', CURRENT_DECEL) command];
            end
        end
        
    end % methods


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center window
            SS = get(0,'ScreenSize');
            app.UIFigure.Position(1:2) = (SS(3:4) - app.UIFigure.Position(3:4)) ./ 2;
            
            % Store all tabs for switching later
            global TABS
            TABS = get(app.TabGroup, 'Children');
            % Usage to go to third tab: app.TabGroup.SelectedTab = TABS(3);
            
            Connect();
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if Connect()
                app.DebugResponseText.Value = '[Connected]';
                app.ConnectedLamp.Color = [0 1 0]; % Green
                Group();
            else
                app.DebugResponseText.Value = '[Error]';
                app.ConnectedLamp.Color = [1 0 0]; % Red
            end
        end

        % Button pushed function: DebugHomeButton
        function DebugHomeButtonPushed(app, event)
            global ESP;
            if ~isempty(ESP)
                Home(app);
            else
                app.DebugResponseText.Value = '[disconnected]';
            end
        end

        % Button pushed function: DebugQueryButton
        function DebugQueryButtonPushed(app, event)
            global ESP;
            if isempty(ESP)
                app.DebugResponseText.Value = '[Disconnected]';
                return;
            end
            
            command = app.DebugCommandField.Value;
            responseIsString =  app.DebugStringCheckBox.Value;
            
            app.DebugResponseText.Value = '';
            response = Query(app, command, responseIsString);
            
            if isempty(response)
               response = '[Timeout]';
               responseIsString = true;
            end
            
            if (~responseIsString)
                response = num2cell(response)'; 
                response = strtrim(sprintf('%f ' , response{:}));
            end
            
            app.DebugResponseText.Value = response;
        end

        % Button pushed function: DebugResetButton
        function DebugResetButtonPushed(app, event)
            Disconnect(true);
            app.DebugResponseText.Value = '[Reset] [Disconnected]';
            app.ConnectedLamp.Color = [1 0 0]; % Red
        end

        % Button pushed function: DebugSendButton
        function DebugSendButtonPushed(app, event)
            global ESP;
            if isempty(ESP)
                app.DebugResponseText.Value = '[Disconnected]';
                return;
            end
            
            command = app.DebugCommandField.Value;
            
            if ~Send(command)
                app.DebugResponseText.Value = '[Error]';
                return;
            end
            
            app.DebugResponseText.Value = '[Sent]';
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            Disconnect(app);
            app.DebugResponseText.Value = '[Disconnected]';
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            app.ConnectedLamp.Color = [1 1 0]; % Yellow
            Send('ST;AB'); % Stop motion, trigger e-stop, turn off all motors.
        end

        % Button pushed function: PrintBrowseButton
        function PrintBrowseButtonPushed(app, event)
            
            [filename, pathname] = uigetfile({...
                '*.gcode','G-Code File';...
                '*.*','All Files' },...
            	'Choose G-Code file',...
                app.PrintFilepathField.Value);
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';% Prevents loss of focus bug after file picker closes.
            
            if ~ischar(filename) % If they cancel
        	return;
            end
            
            app.PrintFilepathField.Value = strcat(pathname, filename);
        end

        % Callback function
        function PrintOTFCheckBoxValueChanged(app, event)
            app.PrintFilepathField.Value = ''; % Clear the textbox because the accepted file extensions change.
        end

        % Button pushed function: PrintStartButton
        function PrintStartButtonPushed(app, event)
            global ESP
            
            if ~Connect(app)
                uiwait(warndlg('Cannot detect printer!', 'No Connection', 'modal'));
                return;
            end
            Home(app);
            %app.PrintStartButton.Enable = 'off';
            GCodeStream(app, app.PrintFilepathField.Value);
            %app.PrintStartButton.Enable = 'on';
        end

        % Button pushed function: PrintStopButton
        function PrintStopButtonPushed(app, event)
            
        end

        % Button pushed function: RtAddButton
        function RtAddButtonPushed(app, event)
            global RT_QUEUE;
            
            if length(RT_QUEUE) < 10
                command = RtEvaluate(app);
                RT_QUEUE{length(RT_QUEUE)+1} = command;
                app.RtQueueField.Value = length(RT_QUEUE);
            end
        end

        % Button pushed function: RtClearButton
        function RtClearButtonPushed(app, event)
            global RT_QUEUE;
            RT_QUEUE = {};
            app.RtQueueField.Value = 0;
        end

        % Button pushed function: RtExecuteButton
        function RtExecuteButtonPushed(app, event)
            global RT_QUEUE;
            for n = 1:length(RT_QUEUE)
                if n <= 10
                    Send(RT_QUEUE{n});
                end
            end
        end

        % Button pushed function: RtGoButton
        function RtGoButtonPushed(app, event)
            command = RtEvaluate(app);
            Send(command);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            global canSwitchTab;
            
            if ~canSwitchTab
                app.TabGroup.SelectedTab = event.OldValue;
            end
            
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            global RT_QUEUE;
            msgbox(RT_QUEUE, 'Queued commands');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'ESP Printer Manager';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PrintTab = uitab(app.TabGroup);
            app.PrintTab.Title = 'Print';
            app.PrintStartButton = uibutton(app.PrintTab, 'push');
            app.PrintStartButton.ButtonPushedFcn = createCallbackFcn(app, @PrintStartButtonPushed, true);
            app.PrintStartButton.Text = 'Start Print';
            app.PrintStopButton = uibutton(app.PrintTab, 'push');
            app.PrintStopButton.ButtonPushedFcn = createCallbackFcn(app, @PrintStopButtonPushed, true);
            app.PrintStopButton.Enable = 'off';
            app.PrintStopButton.Text = 'Stop Print';
            app.FilePanel = uipanel(app.PrintTab);
            app.FilePanel.Title = 'File';
            app.PrintBrowseButton = uibutton(app.FilePanel, 'push');
            app.PrintBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @PrintBrowseButtonPushed, true);
            app.PrintBrowseButton.Text = 'Browse';
            app.PrintFilepathField = uieditfield(app.FilePanel, 'text');
            app.CalculateETACheckBox = uicheckbox(app.FilePanel);
            app.CalculateETACheckBox.Text = 'Calculate ETA';
            app.RealtimeTab = uitab(app.TabGroup);
            app.RealtimeTab.Title = 'Realtime';
            app.RtGoButton = uibutton(app.RealtimeTab, 'push');
            app.RtGoButton.ButtonPushedFcn = createCallbackFcn(app, @RtGoButtonPushed, true);
            app.RtGoButton.Text = 'Go now';
            app.RtQueueingPanel = uipanel(app.RealtimeTab);
            app.RtQueueingPanel.Title = 'Queueing';
            app.RtAddButton = uibutton(app.RtQueueingPanel, 'push');
            app.RtAddButton.ButtonPushedFcn = createCallbackFcn(app, @RtAddButtonPushed, true);
            app.RtAddButton.Text = 'Add';
            app.RtClearButton = uibutton(app.RtQueueingPanel, 'push');
            app.RtClearButton.ButtonPushedFcn = createCallbackFcn(app, @RtClearButtonPushed, true);
            app.RtClearButton.Text = 'Clear';
            app.ViewButton = uibutton(app.RtQueueingPanel, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.RtExecuteButton = uibutton(app.RtQueueingPanel, 'push');
            app.RtExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @RtExecuteButtonPushed, true);
            app.RtExecuteButton.Text = 'Execute';
            app.CommandTabGroup = uitabgroup(app.RealtimeTab);
            app.LineTab = uitab(app.CommandTabGroup);
            app.LineTab.Title = 'Line';
            app.XLabel_2 = uilabel(app.LineTab);
            app.XLabel_2.Text = 'X';
            app.RtXLineField = uieditfield(app.LineTab, 'numeric');
            app.RtXLineField.Limits = [-160 160];
            app.RtXLineField.ValueDisplayFormat = '%11.7g';
            app.YEditFieldLabel_5 = uilabel(app.LineTab);
            app.YEditFieldLabel_5.Text = 'Y';
            app.RtYLineField = uieditfield(app.LineTab, 'numeric');
            app.RtYLineField.Limits = [-160 160];
            app.RtYLineField.ValueDisplayFormat = '%11.7g';
            app.ZLabel = uilabel(app.LineTab);
            app.ZLabel.Text = 'Z';
            app.RtZLineField = uieditfield(app.LineTab, 'numeric');
            app.RtZLineField.Limits = [-160 160];
            app.RtZLineField.ValueDisplayFormat = '%11.7g';
            app.RtRelativeCheckBox = uicheckbox(app.LineTab);
            app.RtRelativeCheckBox.Text = 'Relative space';
            app.CurveTab = uitab(app.CommandTabGroup);
            app.CurveTab.Title = 'Curve';
            app.XLabel = uilabel(app.CurveTab);
            app.XLabel.Text = 'X';
            app.RtXCurveField = uieditfield(app.CurveTab, 'numeric');
            app.RtXCurveField.ValueDisplayFormat = '%11.7g';
            app.YEditFieldLabel_6 = uilabel(app.CurveTab);
            app.YEditFieldLabel_6.Text = 'Y';
            app.RtYCurveField = uieditfield(app.CurveTab, 'numeric');
            app.RtYCurveField.ValueDisplayFormat = '%11.7g';
            app.ALabel = uilabel(app.CurveTab);
            app.ALabel.Text = 'A';
            app.RtACurveField = uieditfield(app.CurveTab, 'numeric');
            app.RtACurveField.ValueDisplayFormat = '%11.7g';
            app.indegreesCCWLabel = uilabel(app.CurveTab);
            app.indegreesCCWLabel.Text = 'in degrees CCW';
            app.DecelLabel = uilabel(app.RealtimeTab);
            app.DecelLabel.Text = 'Decel:';
            app.RtDecelField = uieditfield(app.RealtimeTab, 'numeric');
            app.RtDecelField.Limits = [0 170];
            app.RtDecelField.Value = 50;
            app.AccelLabel = uilabel(app.RealtimeTab);
            app.AccelLabel.Text = 'Accel:';
            app.RtAccelField = uieditfield(app.RealtimeTab, 'numeric');
            app.RtAccelField.Limits = [0 170];
            app.RtAccelField.Value = 50;
            app.CommandsqueuedLabel = uilabel(app.RealtimeTab);
            app.CommandsqueuedLabel.Text = 'Commands queued:';
            app.RtQueueField = uieditfield(app.RealtimeTab, 'numeric');
            app.RtQueueField.Limits = [0 99];
            app.RtQueueField.ValueDisplayFormat = '%.0f';
            app.RtQueueField.Editable = 'off';
            app.VelLabel = uilabel(app.RealtimeTab);
            app.VelLabel.Text = 'Vel:';
            app.RtVelocityField = uieditfield(app.RealtimeTab, 'numeric');
            app.RtVelocityField.Limits = [0 170];
            app.RtVelocityField.Value = 50;
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.DebugSendButton = uibutton(app.DebugTab, 'push');
            app.DebugSendButton.ButtonPushedFcn = createCallbackFcn(app, @DebugSendButtonPushed, true);
            app.DebugSendButton.Text = 'Send';
            app.DebugResetButton = uibutton(app.DebugTab, 'push');
            app.DebugResetButton.ButtonPushedFcn = createCallbackFcn(app, @DebugResetButtonPushed, true);
            app.DebugResetButton.Text = 'Reset';
            app.CommandEditFieldLabel_2 = uilabel(app.DebugTab);
            app.CommandEditFieldLabel_2.Text = 'Command';
            app.DebugCommandField = uieditfield(app.DebugTab, 'text');
            app.ResponseTextAreaLabel_2 = uilabel(app.DebugTab);
            app.ResponseTextAreaLabel_2.Text = 'Response';
            app.DebugResponseText = uitextarea(app.DebugTab);
            app.DebugResponseText.Editable = 'off';
            app.DebugStringCheckBox = uicheckbox(app.DebugTab);
            app.DebugStringCheckBox.Text = 'Expect';
            app.stringLabel_2 = uilabel(app.DebugTab);
            app.stringLabel_2.Text = 'string';
            app.DebugQueryButton = uibutton(app.DebugTab, 'push');
            app.DebugQueryButton.ButtonPushedFcn = createCallbackFcn(app, @DebugQueryButtonPushed, true);
            app.DebugQueryButton.Text = 'Query';
            app.DebugHomeButton = uibutton(app.DebugTab, 'push');
            app.DebugHomeButton.ButtonPushedFcn = createCallbackFcn(app, @DebugHomeButtonPushed, true);
            app.DebugHomeButton.Text = 'Home';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectedLabel = uilabel(app.UIFigure);
            app.ConnectedLabel.Text = 'Connected?';
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-STOP';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Printer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end